classdef EncStegoApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EncStegoUIFigure                matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ExitMenu                        matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        InstructionsMenu                matlab.ui.container.Menu
        AboutMenu                       matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        HideMediaTab_2                  matlab.ui.container.Tab
        StegoProcessPanel               matlab.ui.container.Panel
        EnterausernameEditFieldLabel    matlab.ui.control.Label
        EnterausernameEditField         matlab.ui.control.EditField
        ChooseamediumButtonGroup        matlab.ui.container.ButtonGroup
        ImageButton                     matlab.ui.control.RadioButton
        TextButton                      matlab.ui.control.RadioButton
        EnterthesecretmessageTextAreaLabel  matlab.ui.control.Label
        EnterthesecretmessageTextArea   matlab.ui.control.TextArea
        HideMeButton                    matlab.ui.control.Button
        GeneratedKeysPanel              matlab.ui.container.Panel
        LoginLabel                      matlab.ui.control.Label
        UnlockLabel                     matlab.ui.control.Label
        Loginkey                        matlab.ui.control.Label
        Passkey                         matlab.ui.control.Label
        BrowseButton_2                  matlab.ui.control.Button
        SelectsecretimageLabel          matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        ImagenameLabel                  matlab.ui.control.Label
        ImagenameFn                     matlab.ui.control.Label
        BrowseButton_4                  matlab.ui.control.Button
        SelectsavedirectoryLabel        matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        SelectanimageLabel              matlab.ui.control.Label
        Steps1SelectacoverimageininLabel  matlab.ui.control.Label
        ImagePanel                      matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        DecodeSecretTab                 matlab.ui.container.Tab
        EntertheloginkeyEditFieldLabel  matlab.ui.control.Label
        EntertheloginkeyEditField       matlab.ui.control.EditField
        LoginEnterBtn                   matlab.ui.control.Button
        ClearDec                        matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        RevealSecretButton              matlab.ui.control.Button
        BrowseButton_3                  matlab.ui.control.Button
        SelectstegoimageLabel           matlab.ui.control.Label
        EntertheunlockkeyEditFieldLabel  matlab.ui.control.Label
        EntertheunlockkeyEditField      matlab.ui.control.EditField
        SecretMediumPanel               matlab.ui.container.Panel
        SecretText                      matlab.ui.control.TextArea
        MediaPanel                      matlab.ui.container.Panel
        UIAxes2                         matlab.ui.control.UIAxes
        ImagePanel_2                    matlab.ui.container.Panel
        StegoImgBox                     matlab.ui.control.UIAxes
        Steps1SelectacoverimageininLabel_2  matlab.ui.control.Label
    end

    properties (Access = public)
        Property % Description
        % general
        currentDirectory
        workFolder
        
        % encoding
        coverFile
        cover_img
        secretFile
        secretFile1
        secretImg
        stegoName1
        savePath
        
        % decoding
        stegoImg
        stego_img
        stegoFile
        stegoFile1
        code
        stegoName
        key_tbl
        key_enc
        tries = 1    
    end
    
    methods (Access = private)
        function ClearEncodeScreen(app)
            app.EnterausernameEditField.Value = "";
            app.EnterthesecretmessageTextArea.Value = "";
            app.ChooseamediumButtonGroup.SelectedObject = app.ImageButton;
            app.ImagenameLabel.Visible = false;
            app.ImagenameFn.Visible = false;
            app.Loginkey.Text = "";
            app.Passkey.Text = "";
            app.BrowseButton_2.Visible = true;
            app.SelectsecretimageLabel.Visible = true;
            app.EnterthesecretmessageTextArea.Visible = false;
            app.EnterthesecretmessageTextAreaLabel.Visible = false;
            app.BrowseButton_4.Enable = false;
            app.HideMeButton.Enable = false;
            app.BrowseButton.Enable = true;
            app.ImageButton.Enable = true;
            app.TextButton.Enable = true;
            app.BrowseButton_2.Enable = true;
            app.EnterausernameEditField.Editable = true;
            app.EnterthesecretmessageTextArea.Editable = true;
        end
        
        function ClearDecodeScreen(app)
            app.BrowseButton_3.Enable = true;
            app.ClearDec.Enable = true;
            app.LoginEnterBtn.Enable = false;
            app.EntertheloginkeyEditField.Value = "";
            app.EntertheunlockkeyEditField.Value = "";
            app.EntertheunlockkeyEditField.Editable = false;
            app.SecretMediumPanel.Visible = false;
            app.SecretText.Value = "";
            app.MediaPanel.Visible = false;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.EncStegoUIFigure,"center");
            app.currentDirectory = pwd;
            
            if ~exist('Workspaces', 'dir')
                % If launched for the very first time, it creates a 
                % 'Workspaces' folder for user info in the current
                % directory
                mkdir Workspaces
            end
            app.workFolder = strcat(app.currentDirectory, '\Workspaces');

            % makes the Workspaces folder hidden
            fileattrib(app.workFolder,'+h','','s')
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [app.coverFile, path] = uigetfile( ...
                {'*.bmp', ...
                    'Image file (*.bmp)'}, ...
                    'Select a File');
                
            app.coverFile = strcat(path, app.coverFile);
            if strcmp(app.coverFile, "")
                return;
            end

            app.cover_img = imread(app.coverFile);       
            
            % Display image and stretch to fill axes
            I = imshow(app.cover_img, 'Parent', app.UIAxes, ...
                'XData', [1 app.UIAxes.Position(3)], ...
                'YData', [1 app.UIAxes.Position(4)]);
            % Set limits of axes
            app.UIAxes.XLim = [0 I.XData(2)];
            app.UIAxes.YLim = [0 I.YData(2)];          
            
            app.ImagePanel.Visible = true;
            app.StegoProcessPanel.Visible = 'on';
        end

        % Selection changed function: ChooseamediumButtonGroup
        function ChooseamediumButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseamediumButtonGroup.SelectedObject;
            if(selectedButton == app.TextButton)
                app.EnterthesecretmessageTextArea.Visible = true;
                app.EnterthesecretmessageTextAreaLabel.Visible = true;
                app.BrowseButton_2.Visible = false;
                app.SelectsecretimageLabel.Visible = false;
                app.ImagenameLabel.Visible = false;
                app.ImagenameFn.Visible = false;
            else
                app.EnterthesecretmessageTextArea.Visible = false;
                app.EnterthesecretmessageTextAreaLabel.Visible = false;
                app.BrowseButton_2.Visible = true;
                app.SelectsecretimageLabel.Visible = true;
                app.EnterthesecretmessageTextArea.Value = "";
            end
            app.HideMeButton.Enable = false;
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [app.secretFile1, path] = uigetfile( ...
                {'*.tif;*.tiff;*.bmp;*.jpg;*jpeg;*.png', ...
                    'Image file (*.tif, *.tiff, *.bmp, *jpg, *jpeg, *.png)'}, ...
                    'Select a File');
            app.secretFile = strcat(path, app.secretFile1);
            if strcmp(app.secretFile, "")
                return;
            end
            app.ImagenameLabel.Visible = true;
            app.ImagenameFn.Visible = true;
            app.ImagenameLabel.Text = 'Image name: ';
            app.ImagenameFn.Text = app.secretFile1;
            app.BrowseButton_4.Enable = true;
        end

        % Button pushed function: HideMeButton
        function HideMeButtonPushed(app, event)
            selectedButton = app.ChooseamediumButtonGroup.SelectedObject;
            user = app.EnterausernameEditField.Value;
            
            % checks if username field is empty
            if (user == "") 
                msgbox('Field cannot be blank! Enter a username.', 'Error!');
                return;
            % checks if image is empty when image button is selected
            elseif ((selectedButton == app.ImageButton) && isempty(app.secretFile))
                msgbox('Secret medium cannot be empty!', 'Error!');
                return;               
            % checks if the textbox is empty when text button is selected
            elseif ((selectedButton == app.TextButton) && ...
                     isempty(app.EnterthesecretmessageTextArea.Value))
                msgbox('Secret medium cannot be empty!', 'Error!');
                return;                
            else
                if(selectedButton == app.ImageButton)
                    if (strcmp(app.secretFile,""))
                        return;
                    else
                        app.secretImg = imread(app.secretFile);
                        app.secretImg = double(app.secretImg);
                        [C_S, R_S, D_S] = size(app.secretImg);
                        if (D_S == 1)
                            secret = DiffMatrix(app.secretImg);
                        else
                            secret = DiffMatrix3(app.secretImg);
                        end

                    end
                else
                    msg = app.EnterthesecretmessageTextArea.Value;
                    msg = msg{:};
                    [R_S, C_S] = size(msg);
                    secret = double(msg)';             
                end
                
                [app.code, codeStream] = HuffmanEncoder(secret);
                [R,C,D] = size(app.cover_img);
                app.stego_img = app.cover_img;
                streamLen = length(codeStream);
                i = 1;
                for ii = 1:C
                    for jj = 1:R
                        for kk = 1:D
                            if (i <= streamLen)
                                lsb = mod(double(app.cover_img(jj,ii,kk)), 2);
                                temp = xor(codeStream(i), lsb);
                                app.stego_img(jj,ii,kk) = app.cover_img(jj,ii,kk) + double(temp);
                                i = i + 1;
                            end
                        end
                    end
                end    

                % integrity check: temporary decode to ensure a 
                % Huffman codestream match before writing the stego
                % image
                len = length(codeStream);
                codeStream_dec = zeros(len,1);
                len1 = 1;
                for ii = 1:C
                    for jj = 1:R
                        for kk = 1:D
                            if (len1 <= len)
                                codeStream_dec(len1) = mod(app.stego_img(jj,ii,kk), 2);
                                len1 = len1 + 1;
                            end
                        end
                    end
                end
                check = isequal(codeStream, codeStream_dec);
                % -------------------------------------------------
              
                if (check == 1)
                    if (selectedButton == app.ImageButton) 
                        app.key_tbl = [C_S R_S D_S length(codeStream)];
                    else
                        app.key_tbl = [C_S R_S length(codeStream)];
                    end
                    key_str = strcat(num2str(R_S),num2str(C_S),num2str(streamLen));
        
                    key_user = user(randperm(length(user)));
                    app.key_enc = key_str(randperm(length(key_str)));
                    
                    num = randi([10000,99999],1,1);
                    app.stegoName = strcat(app.currentDirectory, '\', strcat(key_user,...
                        num2str(num)),'.bmp');
                    
                    user1 = strcat(app.workFolder, '\', key_user);
                    code = app.code;
                    key_tbl = app.key_tbl;
                    key_enc = app.key_enc;
                    stegoName = app.stegoName;
                    save(user1, 'code', 'key_tbl', 'key_enc', 'stegoName');    
    
                    imwrite(app.stego_img,app.stegoName);
                    app.Loginkey.Text = key_user;
                    app.Passkey.Text = app.key_enc;
    
                    app.HideMeButton.Enable = false;
                    app.ResetButton.Enable = false;
                    app.ImageButton.Enable = false;
                    app.TextButton.Enable = false;
                    app.BrowseButton.Enable = false;
                    app.BrowseButton_2.Enable = false;
                    app.BrowseButton_4.Enable = false;
                    app.EnterausernameEditField.Editable = false;
                    app.EnterthesecretmessageTextArea.Editable = false;
                    
                    uiwait(msgbox('The media has been successfully encoded!', 'Success!'));
                else
                    msgbox('The secret medium cannot be hidden! Please try again.','Error')
                end
                app.BrowseButton.Enable = false;    
                ClearEncodeScreen(app);
                app.ImagePanel.Visible = false;
                app.StegoProcessPanel.Visible = false;
            end   
           
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            ClearEncodeScreen(app);
        end

        % Value changed function: EntertheloginkeyEditField
        function EntertheloginkeyEditFieldValueChanged(app, event)
            value = app.EntertheloginkeyEditField.Value;
            if (value ~= "") 
                app.LoginEnterBtn.Enable = true;
            else
                app.LoginEnterBtn.Enable = false;
            end
        end

        % Button pushed function: ClearDec
        function ClearDecButtonPushed(app, event)
            app.EntertheloginkeyEditField.Value = "";
            app.LoginEnterBtn.Enable = false;
        end

        % Button pushed function: LoginEnterBtn
        function LoginEnterBtnButtonPushed(app, event)
            user = app.EntertheloginkeyEditField.Value;
%             path = 'C:\StegoProject\Workspaces\';
            userWS = strcat(app.workFolder, '\', user,'.mat');
            check = exist(userWS, 'file');
            if (check == 2)
                load(userWS, 'code', 'stegoName', 'key_tbl', 'key_enc');
                app.code = code;
                app.stegoName = stegoName;
                app.key_tbl = key_tbl;
                app.key_enc = key_enc;
                msgbox(sprintf('Welcome %s!', user), 'Success!');      
                app.Panel_2.Visible = true;
                app.LoginEnterBtn.Enable = false;
                app.ClearDec.Enable = false;
            else
                msgbox('User does not exist!', 'Error!');
                ClearDecodeScreen(app);
            end
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            [app.stegoImg, path] = uigetfile( ...
                {'*.jpg;*.jpeg;*.tif;*.tiff;*.bmp', ...
                    'Image file (*.jpg, *.jpeg, *.tif, *.tiff, *.bmp)'}, ...
                    'Select a File');

            app.stegoFile = strcat(path, app.stegoImg);
            if strcmp(app.stegoFile, "")
                return;
            end
            app.stego_img = imread(app.stegoFile);       
             
            if (strcmp(app.stegoFile, app.stegoName) == 1)
                % Display image and stretch to fill axes
                I = imshow(app.stego_img, 'Parent', app.StegoImgBox, ...
                    'XData', [1 app.StegoImgBox.Position(3)], ...
                    'YData', [1 app.StegoImgBox.Position(4)]);
                % Set limits of axes
                app.StegoImgBox.XLim = [0 I.XData(2)];
                app.StegoImgBox.YLim = [0 I.YData(2)];          
    
                app.ImagePanel_2.Visible = 'on';        
                app.BrowseButton_3.Enable = false;
            else
                app.ImagePanel_2.Visible = 'off';        
                msgbox('Selected image is not associated with the user!', 'Error!');
            end
            app.EntertheunlockkeyEditField.Editable = true;

        end

        % Value changed function: EntertheunlockkeyEditField
        function EntertheunlockkeyEditFieldValueChanged(app, event)
            value = app.EntertheunlockkeyEditField.Value;
            if (value ~= "" && app.stegoFile ~= "") 
                app.RevealSecretButton.Enable = true;
            else
                app.RevealSecretButton.Enable = false;
            end
          
        end

        % Button pushed function: RevealSecretButton
        function RevealSecretButtonPushed(app, event)
            val = app.EntertheunlockkeyEditField.Value;
            if (strcmp(val, app.key_enc) == 1)
                app.stego_img = double(app.stego_img);     
                [R,C,D] = size(app.stego_img);
                if(length(app.key_tbl) == 4)
                    len = app.key_tbl(4);
                else
                    len = app.key_tbl(3);
                end
                    codeStream_dec = zeros(len, 1);
    
                len1 = 1;
                for ii = 1:C
                    for jj = 1:R
                        for kk = 1:D
                            if (len1 <= len)
                                codeStream_dec(len1) = mod(app.stego_img(jj,ii,kk), 2);
                                len1 = len1 + 1;
                            end
                        end
                    end
                end
    
                dsig = huffmandeco(codeStream_dec,app.code);
                % secret medium is text
                if(app.key_tbl(2) == 1)
                    stego_diff_img = zeros(app.key_tbl(2), app.key_tbl(1));
                    zz = 1;
                    for i = 1:app.key_tbl(2)
                        for j = 1:app.key_tbl(1)
                            if (zz <= length(dsig))
                                stego_diff_img(i,j) = dsig(zz);
                                zz = zz + 1;
                            end
                        end
                    end
                    stego_dec = stego_diff_img;
                    decoded_msg = char(stego_dec);
                    app.SecretText.Value = decoded_msg;
                    app.SecretText.Visible = true;
                    app.MediaPanel.Visible = false;
                % secret medium is image
                else 
                    zz = 1;
                    % secret image is RGB 
                    if (app.key_tbl(3) == 3)
                        stego_diff_img = zeros(app.key_tbl(1), 3*app.key_tbl(2));
                        for i = 1:app.key_tbl(1)
                            for j = 1:3*app.key_tbl(2)
                                if (zz <= length(dsig))
                                    stego_diff_img(i, j) = dsig(zz);
                                    zz = zz + 1;
                                end
                            end
                        end
                        stego_dec = DiffToOrig3(stego_diff_img);
                    % secret image is grayscale
                    else
                        stego_diff_img = zeros(app.key_tbl(1), app.key_tbl(2));
                        for i = 1:app.key_tbl(1)
                            for j = 1:app.key_tbl(2)
                                if (zz <= length(dsig))
                                    stego_diff_img(i,j) = dsig(zz);
                                    zz = zz + 1;
                                end
                            end
                        end    
                        stego_dec = DiffToOrig(stego_diff_img);
                    end
                    imshow(stego_dec, [], 'Parent', app.UIAxes2, ...
                      'InitialMagnification', 'fit');
                    app.MediaPanel.Visible = true;
                end
                app.RevealSecretButton.Enable = false;
                app.SecretMediumPanel.Visible = true;
    
                uiwait(msgbox('The hidden medium has been successfully revealed!', 'Success!'));
                
                ClearDecodeScreen(app);
                app.ImagePanel_2.Visible = false;
                app.Panel_2.Visible = false;
            % process when key input is invalid
            else
                app.SecretMediumPanel.Visible = false;
                % maximum number of guesses is 3
                if (app.tries == 3)
                    msgbox('Maximum attempts reached! Exiting...',...
                            'Error!');
                    ClearDecodeScreen(app);
                    app.ImagePanel_2.Visible = false;
                    app.Panel_2.Visible = false;
                    app.tries = 1;
                else
                    msgbox(sprintf('Incorrect key! Tries = %d\n', app.tries),...
                            'Error!');                    
                    app.tries = app.tries + 1;    
                    app.EntertheunlockkeyEditField.Value = "";
                    app.RevealSecretButton.Enable = false;
                    return;
                end
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if (selectedTab == app.DecodeSecretTab)
                app.Panel_2.Visible = false;
                app.ImagePanel_2.Visible = false;
                ClearEncodeScreen(app);
            else
                app.StegoProcessPanel.Visible = false;
                app.ImagePanel.Visible = false;    
                ClearDecodeScreen(app);
            end
        end

        % Value changed function: EnterthesecretmessageTextArea
        function EnterthesecretmessageTextAreaValueChanged(app, event)
            value = app.EnterthesecretmessageTextArea.Value;
            if (value ~= "")
                app.BrowseButton_4.Enable = true;
            else
                app.BrowseButton_4.Enable = false;
            end
        end

        % Button pushed function: BrowseButton_4
        function BrowseButton_4Pushed(app, event)
            app.currentDirectory = uigetdir;
            app.HideMeButton.Enable = true;
        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            closereq;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            About;
        end

        % Menu selected function: InstructionsMenu
        function InstructionsMenuSelected(app, event)
            Instructions;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EncStegoUIFigure = uifigure('Visible', 'off');
            app.EncStegoUIFigure.Name = 'EncStego';
            app.FileMenu = uimenu(app.EncStegoUIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.HelpMenu = uimenu(app.EncStegoUIFigure);
            app.HelpMenu.Text = 'Help';
            app.InstructionsMenu = uimenu(app.HelpMenu);
            app.InstructionsMenu.MenuSelectedFcn = createCallbackFcn(app, @InstructionsMenuSelected, true);
            app.InstructionsMenu.Text = 'Instructions';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.TabGroup = uitabgroup(app.EncStegoUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.HideMediaTab_2 = uitab(app.TabGroup);
            app.HideMediaTab_2.Title = 'Hide Media';
            app.StegoProcessPanel = uipanel(app.HideMediaTab_2);
            app.StegoProcessPanel.Title = 'Stego Process';
            app.StegoProcessPanel.Visible = 'off';
            app.EnterausernameEditFieldLabel = uilabel(app.StegoProcessPanel);
            app.EnterausernameEditFieldLabel.Text = 'Enter a username:';
            app.EnterausernameEditField = uieditfield(app.StegoProcessPanel, 'text');
            app.ChooseamediumButtonGroup = uibuttongroup(app.StegoProcessPanel);
            app.ChooseamediumButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseamediumButtonGroupSelectionChanged, true);
            app.ChooseamediumButtonGroup.Title = 'Choose a medium:';
            app.ImageButton = uiradiobutton(app.ChooseamediumButtonGroup);
            app.ImageButton.Text = 'Image';
            app.ImageButton.Value = true;
            app.TextButton = uiradiobutton(app.ChooseamediumButtonGroup);
            app.TextButton.Text = 'Text';
            app.EnterthesecretmessageTextAreaLabel = uilabel(app.StegoProcessPanel);
            app.EnterthesecretmessageTextAreaLabel.Visible = 'off';
            app.EnterthesecretmessageTextAreaLabel.Text = {'Enter the'; 'secret message:'};
            app.EnterthesecretmessageTextArea = uitextarea(app.StegoProcessPanel);
            app.EnterthesecretmessageTextArea.ValueChangedFcn = createCallbackFcn(app, @EnterthesecretmessageTextAreaValueChanged, true);
            app.EnterthesecretmessageTextArea.Visible = 'off';
            app.HideMeButton = uibutton(app.StegoProcessPanel, 'push');
            app.HideMeButton.ButtonPushedFcn = createCallbackFcn(app, @HideMeButtonPushed, true);
            app.HideMeButton.Enable = 'off';
            app.HideMeButton.Text = 'Hide Me!';
            app.GeneratedKeysPanel = uipanel(app.StegoProcessPanel);
            app.GeneratedKeysPanel.Title = 'Generated Keys';
            app.LoginLabel = uilabel(app.GeneratedKeysPanel);
            app.LoginLabel.Text = 'Login:';
            app.UnlockLabel = uilabel(app.GeneratedKeysPanel);
            app.UnlockLabel.Text = 'Unlock:';
            app.Loginkey = uilabel(app.GeneratedKeysPanel);
            app.Loginkey.Text = '';
            app.Passkey = uilabel(app.GeneratedKeysPanel);
            app.Passkey.Text = '';
            app.BrowseButton_2 = uibutton(app.StegoProcessPanel, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.SelectsecretimageLabel = uilabel(app.StegoProcessPanel);
            app.SelectsecretimageLabel.Text = 'Select secret image:';
            app.ResetButton = uibutton(app.StegoProcessPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ImagenameLabel = uilabel(app.StegoProcessPanel);
            app.ImagenameLabel.Visible = 'off';
            app.ImagenameLabel.Text = 'Image name:';
            app.ImagenameFn = uilabel(app.StegoProcessPanel);
            app.ImagenameFn.Visible = 'off';
            app.ImagenameFn.Text = '';
            app.BrowseButton_4 = uibutton(app.StegoProcessPanel, 'push');
            app.BrowseButton_4.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_4Pushed, true);
            app.BrowseButton_4.Enable = 'off';
            app.BrowseButton_4.Text = 'Browse';
            app.SelectsavedirectoryLabel = uilabel(app.StegoProcessPanel);
            app.SelectsavedirectoryLabel.Text = 'Select save directory:';
            app.Panel = uipanel(app.HideMediaTab_2);
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectanimageLabel = uilabel(app.Panel);
            app.SelectanimageLabel.Text = 'Select an image:';
            app.Steps1SelectacoverimageininLabel = uilabel(app.HideMediaTab_2);
            app.Steps1SelectacoverimageininLabel.Text = {'"There is nothing we like to communicate to others as '; ' much as the the seal of secrecy together with what lies'; ' under it."'; ''; '                                               - Friedrich Nietzsche'};
            app.ImagePanel = uipanel(app.HideMediaTab_2);
            app.UIAxes = uiaxes(app.ImagePanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.Visible = 'off';
            app.DecodeSecretTab = uitab(app.TabGroup);
            app.DecodeSecretTab.Title = 'Decode Secret';
            app.EntertheloginkeyEditFieldLabel = uilabel(app.DecodeSecretTab);
            app.EntertheloginkeyEditFieldLabel.Text = 'Enter the login key:';
            app.EntertheloginkeyEditField = uieditfield(app.DecodeSecretTab, 'text');
            app.EntertheloginkeyEditField.ValueChangedFcn = createCallbackFcn(app, @EntertheloginkeyEditFieldValueChanged, true);
            app.LoginEnterBtn = uibutton(app.DecodeSecretTab, 'push');
            app.LoginEnterBtn.ButtonPushedFcn = createCallbackFcn(app, @LoginEnterBtnButtonPushed, true);
            app.LoginEnterBtn.Enable = 'off';
            app.LoginEnterBtn.Text = 'Login';
            app.ClearDec = uibutton(app.DecodeSecretTab, 'push');
            app.ClearDec.ButtonPushedFcn = createCallbackFcn(app, @ClearDecButtonPushed, true);
            app.ClearDec.Text = 'Clear';
            app.Panel_2 = uipanel(app.DecodeSecretTab);
            app.Panel_2.Visible = 'off';
            app.RevealSecretButton = uibutton(app.Panel_2, 'push');
            app.RevealSecretButton.ButtonPushedFcn = createCallbackFcn(app, @RevealSecretButtonPushed, true);
            app.RevealSecretButton.Enable = 'off';
            app.RevealSecretButton.Text = 'Reveal Secret!';
            app.BrowseButton_3 = uibutton(app.Panel_2, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.SelectstegoimageLabel = uilabel(app.Panel_2);
            app.SelectstegoimageLabel.Text = 'Select stego image:';
            app.EntertheunlockkeyEditFieldLabel = uilabel(app.Panel_2);
            app.EntertheunlockkeyEditFieldLabel.Text = 'Enter the unlock key:';
            app.EntertheunlockkeyEditField = uieditfield(app.Panel_2, 'text');
            app.EntertheunlockkeyEditField.ValueChangedFcn = createCallbackFcn(app, @EntertheunlockkeyEditFieldValueChanged, true);
            app.EntertheunlockkeyEditField.Editable = 'off';
            app.SecretMediumPanel = uipanel(app.Panel_2);
            app.SecretMediumPanel.Title = 'Secret Medium';
            app.SecretMediumPanel.Visible = 'off';
            app.SecretText = uitextarea(app.SecretMediumPanel);
            app.SecretText.Editable = 'off';
            app.SecretText.Visible = 'off';
            app.MediaPanel = uipanel(app.SecretMediumPanel);
            app.UIAxes2 = uiaxes(app.MediaPanel);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.ImagePanel_2 = uipanel(app.DecodeSecretTab);
            app.StegoImgBox = uiaxes(app.ImagePanel_2);
            title(app.StegoImgBox, '')
            xlabel(app.StegoImgBox, '')
            ylabel(app.StegoImgBox, '')
            app.StegoImgBox.Visible = 'off';
            app.Steps1SelectacoverimageininLabel_2 = uilabel(app.DecodeSecretTab);
            app.Steps1SelectacoverimageininLabel_2.Text = {'"There is nothing we like to communicate to others as '; ' much as the the seal of secrecy together with what lies'; ' under it."'; ''; '                                               - Friedrich Nietzsche'};
            % Show the figure after all components are created
            app.EncStegoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EncStegoApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EncStegoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EncStegoUIFigure)
        end
    end
end